classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TextRecogniser           matlab.ui.Figure
        TrenujsieneuronowButton  matlab.ui.control.Button
        ZaetykietujButton        matlab.ui.control.Button
        EditField                matlab.ui.control.EditField
        EditFieldLabel           matlab.ui.control.Label
        RozpoznajtekstButton     matlab.ui.control.Button
        WczytajobrazButton       matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        obrazek_double;
        obrazek_binarny;
        wspolczynniki = {@trenowanie_wspolczynniki};
        obrazek;
         znaki = ['A';'B'; 'C'; 'D'; 'E'; 'F'; 'G'; 'H'; 'I'; 'J'; 'K'; 'L'; 'M'; 'N'; 'O'; 'P'; 'Q'; 'R'; 'S'; 'T'; 'U'; 'V'; 'W'; 'X'; 'Y'; 'Z';'1';'2';'3';'4';'5';'6';'7';'8';'9';'0'];
        siec_neuronowa = load ('neural_net.mat'); 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WczytajobrazButton
        function WczytajobrazButtonPushed(app, event)
            [file, path] = uigetfile({'*.png';'*.jpg';'*.bmp';'*.svg';'*.gif'}, 'Wybierz obrazek:');
            filepath=[path file];
            if isequal(file,0)
               tts('Nie wczytałeś żadnego obrazka!');
            else
               app.obrazek_double = double(imread(filepath))/255;
               app.obrazek = imread(filepath);
               
               imshow(app.obrazek_double,'parent',app.UIAxes);
               tts('Wczytałeś obraz!');
               app.EditField.Value = '';
            end

        end

        % Button pushed function: RozpoznajtekstButton
        function RozpoznajtekstButtonPushed(app, event)
            app.obrazek_binarny = rgb2gray(app.obrazek_double);
            if round(mean(app.obrazek_binarny(:)))==1
                app.obrazek_binarny = ~imbinarize(app.obrazek_binarny);
            else
                app.obrazek_binarny = imbinarize(app.obrazek_binarny);
            end

            app.obrazek_binarny= imclose(app.obrazek_binarny,ones(3));
            kopia = app.obrazek_binarny;
            obiekty = regionprops(kopia,'Image');
            all = regionprops(kopia,'all');
            
            bboxes = vertcat(all.BoundingBox);

           
            xmin = bboxes(:,1);
            ymin = bboxes(:,2);
            xmax = xmin + bboxes(:,3) - 1;
            ymax = ymin + bboxes(:,4) - 1;
            
            expansionAmount = 0.02;
            xmin = (1-expansionAmount) * xmin;
            ymin = (1-expansionAmount) * ymin;
            xmax = (1+expansionAmount) * xmax;
            ymax = (1+expansionAmount) * ymax;
            
            xmin = max(xmin, 1);
            ymin = max(ymin, 1);
            xmax = min(xmax, size(rgb2gray(app.obrazek_double),2));
            ymax = min(ymax, size(rgb2gray(app.obrazek_double),1));
            
            expandedBBoxes = [xmin ymin xmax-xmin+1 ymax-ymin+1];
            IExpandedBBoxes = insertShape(app.obrazek,'Rectangle',expandedBBoxes,'LineWidth',3);
            
            
            imshow(IExpandedBBoxes,'parent',app.UIAxes);


            wektor = zeros(length(obiekty), 16 * 8);
            
            for i = 1:length(obiekty)
                    kopia = obiekty(i).Image;
                    kopia = imresize(kopia, [16, 8]);
                    
                    for k = 1 : 16
                        for l = 1: 8
                            wektor(i, (k-1) * 8 + l) =  kopia(k, l);
                        end
                    end
            end
            out1 = app.siec_neuronowa.nn(wektor');
            result = '';

            temporary = '';

            for i = 1:size(out1,2)
                [~,result] = (max(out1(:,i)));
                temporary = strcat(temporary, app.znaki(result));
            end
      
     
            szerokosc = 0;

            for i = 1 : size(temporary)
                szerokosc = szerokosc + bboxes(i, 3);
            end

            srednia = 5*(szerokosc/ strlength(temporary));

            tempresult = '';

            for i = 1 : strlength(temporary) - 1
                boxik = bboxes(i, 1) + bboxes(i, 3);
                t = i + 1;
                boxik2 = bboxes(t, 1);
                
                if(boxik2 - boxik > srednia)
                    tempresult = strcat(tempresult, temporary(i));
                    tempresult = strcat(tempresult, " ");
                else
                    tempresult = strcat(tempresult, temporary(i));
                end
               
            end

            tempresult = strcat(tempresult, temporary(strlength(temporary)));
            
            
            app.EditField.Value = tempresult;
               

            tts(char(tempresult));
        end

        % Button pushed function: ZaetykietujButton
        function ZaetykietujButtonPushed(app, event)
               

               app.obrazek_binarny = rgb2gray(app.obrazek_double);
            if round(mean(app.obrazek_binarny(:)))==1
                app.obrazek_binarny = ~imbinarize(app.obrazek_binarny);
            else
                app.obrazek_binarny = imbinarize(app.obrazek_binarny);
            end

               app.obrazek_binarny= imclose(app.obrazek_binarny,ones(3));
               kolor=bwlabel(app.obrazek_binarny);
               
               imshow(label2rgb(kolor),'parent',app.UIAxes);

               tts('Obraz został zaetykietowany');
        end

        % Button pushed function: TrenujsieneuronowButton
        function TrenujsieneuronowButtonPushed(app, event)
            app.wspolczynniki{1}();
            app.EditField.Value = '';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TextRecogniser = uifigure('Visible', 'off');
            app.TextRecogniser.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.TextRecogniser);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.WczytajobrazButton = uibutton(app.TextRecogniser, 'push');
            app.WczytajobrazButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajobrazButtonPushed, true);
            app.WczytajobrazButton.Text = 'Wczytaj obraz';
            app.RozpoznajtekstButton = uibutton(app.TextRecogniser, 'push');
            app.RozpoznajtekstButton.ButtonPushedFcn = createCallbackFcn(app, @RozpoznajtekstButtonPushed, true);
            app.RozpoznajtekstButton.Text = 'Rozpoznaj tekst';
            app.EditFieldLabel = uilabel(app.TextRecogniser);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.TextRecogniser, 'text');
            app.ZaetykietujButton = uibutton(app.TextRecogniser, 'push');
            app.ZaetykietujButton.ButtonPushedFcn = createCallbackFcn(app, @ZaetykietujButtonPushed, true);
            app.ZaetykietujButton.Text = 'Zaetykietuj ';
            app.TrenujsieneuronowButton = uibutton(app.TextRecogniser, 'push');
            app.TrenujsieneuronowButton.ButtonPushedFcn = createCallbackFcn(app, @TrenujsieneuronowButtonPushed, true);
            app.TrenujsieneuronowButton.Text = 'Trenuj sieć neuronową';
            % Show the figure after all components are created
            app.TextRecogniser.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TextRecogniser)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TextRecogniser)
        end
    end
end